classdef appproyect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainMovementTab                matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        Image2                         matlab.ui.control.Image
        AnimateButton                  matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        GeometryparametersinmPanel     matlab.ui.container.Panel
        OCLengthEditField              matlab.ui.control.NumericEditField
        OCLengthEditFieldLabel         matlab.ui.control.Label
        OBLengthEditField              matlab.ui.control.NumericEditField
        OBLengthLabel                  matlab.ui.control.Label
        RationEditField                matlab.ui.control.NumericEditField
        RationEditFieldLabel           matlab.ui.control.Label
        DynamicsparametersPanel        matlab.ui.container.Panel
        AngularAcceleration1rads2EditField  matlab.ui.control.NumericEditField
        AngularAcceleration1rads2EditFieldLabel  matlab.ui.control.Label
        AngularVelocity1radsEditField  matlab.ui.control.NumericEditField
        AngularVelocity1radsEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        PositionsGraphTab              matlab.ui.container.Tab
        SeeprogressButton              matlab.ui.control.Button
        UIAxes9                        matlab.ui.control.UIAxes
        UIAxes8                        matlab.ui.control.UIAxes
        VelocitiesGraphsTab            matlab.ui.container.Tab
        SeevelocitiesButton            matlab.ui.control.Button
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        AccelerationsGraphsTab         matlab.ui.container.Tab
        ShowaccelerationsButton        matlab.ui.control.Button
        UIAxes7                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %posiciones

            xlabel(app.UIAxes9, '\theta_1 (rad)')
            ylabel(app.UIAxes9, '\theta_2 (rad)')
            title(app.UIAxes9, 'Analysis of \theta_{2} angle')

            xlabel(app.UIAxes8, '\theta_1 (rad)')
            ylabel(app.UIAxes8, 'R_{CD} (m)')
            title(app.UIAxes8, 'Analysis of R_{CD} position')

            %velocidades

            xlabel(app.UIAxes4, '\theta_1 (rad)')
            ylabel(app.UIAxes4, '\omega_{OA},\omega_{BA} (rad/s)')
            legend(app.UIAxes4,'\omega_{BA}','\omega_{OA}')

            xlabel(app.UIAxes5, '\theta_1 (rad)')
            ylabel(app.UIAxes5, 'V_{Dx}(m/s)')

            %Aceleraciones

            xlabel(app.UIAxes6, '\theta_1 (rad)')
            ylabel(app.UIAxes6, '\alpha_{AB} \alpha_{OA} (rad/s^{2})')

            xlabel(app.UIAxes7, '\theta_1 (rad)')
            ylabel(app.UIAxes7, 'a_{Dx} (m/s^{2})')

            %Showing images and gifts


        end

        % Button pushed function: SeeprogressButton
        function SeeprogressButtonPushed(app, event)
            
            %Calling all of the constant defined values by geometry
            
            n = app.RationEditField.Value;
            Roc = app.OCLengthEditField.Value;
            Rob =app.OBLengthEditField.Value;
            Roa = (1/(n+1))*Rob;
            Rbc = Roc + Rob;

            %Creation of angle vectors for further analysis on graphs in a
            %single cycle

            %nangles = 360;

            theta1 = linspace(0,6*pi,1080);

            %Solution of angle and positions of object on D point:

            theta2 = zeros(1,1080);
            for i =1:1:1080
                theta2(i) = acos((Roa*cos(theta1(i)))/sqrt((Roa)^2 + 2*Roa*Rob*sin(theta1(i))+(Rob)^2));
            end

            Rcd = ((Rbc)./(tan(theta2)));

            %Plotting graphs
            plot(app.UIAxes9,theta1,theta2);

            xlabel(app.UIAxes9, '\theta_1 (rad)')
            ylabel(app.UIAxes9, '\theta_2 (rad)')
            title(app.UIAxes9, 'Analysis of \theta_{2} angle')
            plot(app.UIAxes8,theta1,Rcd);

            xlabel(app.UIAxes8, '\theta_1 (rad)')
            ylabel(app.UIAxes8, 'R_{CD} (m)')
            title(app.UIAxes8, 'Analysis of R_{CD} position')

            %For velocities:
            %w1 = app.AngularVelocity1radsEditField.Value;
            %w2 = ((-tan(theta2).*w1.*Roa.*cos(theta1))./Rbc);
            %Vdx = (-w1.*Roa.*sin(theta1))+(tan(theta2).*w2.*Roa.*cos(theta1));

        end

        % Button pushed function: SeevelocitiesButton
        function SeevelocitiesButtonPushed(app, event)
            %Calling all of the constant defined values by geometry

            n = app.RationEditField.Value;
            Roc = app.OCLengthEditField.Value;
            Rob = app.OBLengthEditField.Value;
            Roa = (1/(n+1))*Rob;
            Rbc = Roc + Rob;

            %Creation of angle vectors for further analysis on graphs in a
            %single cycle

            %nangles = 360;
            theta1 = linspace(0,6*pi,1080);

            %Solution of angle and positions of object on D point:
            theta2 = zeros(1,1080);
            for i =1:1:1080
                theta2(i) = acos((Roa*cos(theta1(i)))/sqrt((Roa)^2 + 2*Roa*Rob*sin(theta1(i))+(Rob)^2));
            end
            %Rcd = ((Rbc)./(tan(theta2)));

            %For velocities:
            woa = app.AngularVelocity1radsEditField.Value;
            %w2 = ((-tan(theta2).*w1.*Roa.*cos(theta1))./Rbc);
            %Vdx = (-w1.*Roa.*sin(theta1))+(tan(atan((Rob + Roa.*sin(theta1))./(Roa.*cos(theta1)))).*w2.*Roa.*cos(theta1));

            Rbax = Roa.*cos(theta1);
            Rbay= Roa.*sin(theta1)+Rob;
            Rba = sqrt(power(Rbax,2) + power(Rbay,2));

            wba= (-Roa.*woa.*sin(theta1))./(Rba.*sin(pi-theta2));
            Vdx = (-wba*Rbc);

            vec = zeros(1080,1);
            for i = 1:1:1080
                vec(i) = woa;
            end

            plot(app.UIAxes4,theta1,wba,'b',theta1,vec,'r');
            xlabel(app.UIAxes4, '\theta_1 (rad)')
            ylabel(app.UIAxes4, '\omega_{OA},\omega_{BA} (rad/s)')
            legend(app.UIAxes4,'\omega_{BA}','\omega_{OA}')
            
            plot(app.UIAxes5,theta1,Vdx);
            xlabel(app.UIAxes5, '\theta_1 (rad)')
            ylabel(app.UIAxes5, 'V_{Dx}(m/s)')

        end

        % Button pushed function: ShowaccelerationsButton
        function ShowaccelerationsButtonPushed(app, event)
            %Calling all of the constant defined values by geometry

            n = app.RationEditField.Value;
            Roc = app.OCLengthEditField.Value;
            Rob =app.OBLengthEditField.Value;
            Roa = (1/(n+1))*Rob;
            Rbc = Roc + Rob;

            %Creation of angle vectors for further analysis on graphs in a
            %single cycle

            %nangles = 360;

            theta1 = linspace(0,6*pi,1080);

            %Solution of angle and positions of object on D point:

            theta2 = zeros(1,1080);
            for i =1:1:1080
                theta2(i) = acos((Roa*cos(theta1(i)))/sqrt((Roa)^2 + 2*Roa*Rob*sin(theta1(i))+(Rob)^2));
            end

            %Rcd = ((Rbc)./(tan(theta2)));

            %For velocities:

            woa = app.AngularVelocity1radsEditField.Value;

            %w2 = ((-tan(theta2).*w1.*Roa.*cos(theta1))./Rbc);
            
            %Vdx = (-w1.*Roa.*sin(theta1))+(tan(atan((Rob + Roa.*sin(theta1))./(Roa.*cos(theta1)))).*w2.*Roa.*cos(theta1));

            Rbax = Roa.*cos(theta1);
            Rbay= Roa.*sin(theta1)+Rob;

            Rba = sqrt(power(Rbax,2) + power(Rbay,2));

            wba= (-Roa.*woa.*sin(theta1))./(Rba.*sin(pi-theta2));
            %Vdx = (-wba*Rbc);

            % Accelerations

            alphaoa = app.AngularAcceleration1rads2EditField.Value;
            alphaab= wba.*wba.*tan(theta2);
            adx= (-(wba.*wba.*Rbc)./tan(theta2))-(wba.*wba.*tan(theta2).*Rbc);


            vec1 = zeros(1080,1);
            for i = 1:1:1080
                vec1(i) = alphaoa;
            end

            plot(app.UIAxes6,theta1,alphaab,'r',theta1,vec1,'b');
    
            xlabel(app.UIAxes6, '\theta_1 (rad)')
            ylabel(app.UIAxes6, '\alpha_{AB} \alpha_{OA} (rad/s^{2})')
            ylim(app.UIAxes6,[-100 100])
            legend(app.UIAxes6,'\alpha_{AB}','\alpha_{OA}')

            plot(app.UIAxes7,theta1,adx);
            xlabel(app.UIAxes7, '\theta_1 (rad)')
            ylabel(app.UIAxes7, 'a_{Dx} (m/s^{2})')
            ylim(app.UIAxes7,[-100 100])


        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            
            n = app.RationEditField.Value;
            Roc = app.OCLengthEditField.Value;
            Rob = app.OBLengthEditField.Value;
            Roa = (1/(n+1))*Rob;
            Rbc = Rob + Roc;
            
            theta1 = 0:0.1:6*pi;
            theta2 = zeros(1,189);
            for i =1:1:189
                theta2(i) = acos((Roa*cos(theta1(i)))/sqrt((Roa)^2 + 2*Roa*Rob*sin(theta1(i))+(Rob)^2));
            end

            grid on
            xlim(app.UIAxes,[-(Roa+Rob) Roa+Rob]), ylim(app.UIAxes,[-Rob-0.5 Rbc+0.5])

            for j = 1:1:189
                plot(app.UIAxes,[0 Roa*cos(theta1(j))],[0 Roa*sin(theta1(j))],'m');
                hold(app.UIAxes, 'on')
                plot(app.UIAxes,0,0,'ko','MarkerFaceColor','k');
                plot(app.UIAxes,0,-Rob,'ko','MarkerFaceColor','k');
                plot(app.UIAxes,Roa*cos(theta1(j)), Roa*sin(theta1(j)),'ko','MarkerFaceColor','k');
                plot(app.UIAxes,Rbc/tan(theta2(j)), Roc,'ko','MarkerFaceColor','k');
                plot(app.UIAxes,[0 Rbc/tan(theta2(j))],[-Rob Roc],'b');
                plot(app.UIAxes,[0 Roa*cos(theta1(j))],[-Rob Roa*sin(theta1(j))],'g');
                hold(app.UIAxes, 'off')
                drawnow;
                pause(0.02)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainMovementTab = uitab(app.TabGroup);
            app.MainMovementTab.Title = 'Main Movement';
            app.Panel = uipanel(app.MainMovementTab);
            app.Panel.Title = 'In this tab you will be able to see the animation of the mechanism, the main picture and the position graphs:';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Simulation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.Panel);
            app.DynamicsparametersPanel = uipanel(app.Panel_2);
            app.DynamicsparametersPanel.Title = 'Dynamics parameters';
            app.AngularVelocity1radsEditFieldLabel = uilabel(app.DynamicsparametersPanel);
            app.AngularVelocity1radsEditFieldLabel.Text = 'Angular Velocity 1 (rad/s)';
            app.AngularVelocity1radsEditField = uieditfield(app.DynamicsparametersPanel, 'numeric');
            app.AngularVelocity1radsEditField.Value = 1;
            app.AngularAcceleration1rads2EditFieldLabel = uilabel(app.DynamicsparametersPanel);
            app.AngularAcceleration1rads2EditFieldLabel.Text = 'Angular Acceleration 1 (rad/s^2)';
            app.AngularAcceleration1rads2EditField = uieditfield(app.DynamicsparametersPanel, 'numeric');
            app.AngularAcceleration1rads2EditField.Value = 1;
            app.GeometryparametersinmPanel = uipanel(app.Panel_2);
            app.GeometryparametersinmPanel.Title = 'Geometry parameters (in m)';
            app.RationEditFieldLabel = uilabel(app.GeometryparametersinmPanel);
            app.RationEditFieldLabel.Text = 'Ratio n';
            app.RationEditField = uieditfield(app.GeometryparametersinmPanel, 'numeric');
            app.RationEditField.Value = 4;
            app.OBLengthLabel = uilabel(app.GeometryparametersinmPanel);
            app.OBLengthLabel.Text = 'O-B Length ';
            app.OBLengthEditField = uieditfield(app.GeometryparametersinmPanel, 'numeric');
            app.OBLengthEditField.Value = 1.25;
            app.OCLengthEditFieldLabel = uilabel(app.GeometryparametersinmPanel);
            app.OCLengthEditFieldLabel.Text = 'O-C Length';
            app.OCLengthEditField = uieditfield(app.GeometryparametersinmPanel, 'numeric');
            app.OCLengthEditField.Value = 1.5;
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MicrosoftTeams-image (2).png');
            app.AnimateButton = uibutton(app.Panel, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'ezgif.com-video-to-gif (1).gif');
            app.PositionsGraphTab = uitab(app.TabGroup);
            app.PositionsGraphTab.Title = 'Positions Graph';
            app.UIAxes8 = uiaxes(app.PositionsGraphTab);
            title(app.UIAxes8, 'Analysis of Rcd position')
            xlabel(app.UIAxes8, 'theta 1 ')
            ylabel(app.UIAxes8, 'Horizontal position of B (m)')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.PositionsGraphTab);
            title(app.UIAxes9, 'Analysis of theta 2 angle')
            xlabel(app.UIAxes9, 'theta 1 ')
            ylabel(app.UIAxes9, 'theta 2 (rad)')
            zlabel(app.UIAxes9, 'Z')
            app.SeeprogressButton = uibutton(app.PositionsGraphTab, 'push');
            app.SeeprogressButton.ButtonPushedFcn = createCallbackFcn(app, @SeeprogressButtonPushed, true);
            app.SeeprogressButton.Text = 'See progress';
            app.VelocitiesGraphsTab = uitab(app.TabGroup);
            app.VelocitiesGraphsTab.Title = 'Velocities Graphs';
            app.UIAxes4 = uiaxes(app.VelocitiesGraphsTab);
            title(app.UIAxes4, 'Analysis of velocities of mechanism')
            xlabel(app.UIAxes4, 'theta1(rad)')
            ylabel(app.UIAxes4, 'Omega1 (rad/s), Omega2(rad/s)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.VelocitiesGraphsTab);
            title(app.UIAxes5, 'Analysis of velocities of mechanism')
            xlabel(app.UIAxes5, 'Theta1 (rad)')
            ylabel(app.UIAxes5, 'Horizontal velocity Vdx(m/s)')
            zlabel(app.UIAxes5, 'Z')
            app.SeevelocitiesButton = uibutton(app.VelocitiesGraphsTab, 'push');
            app.SeevelocitiesButton.ButtonPushedFcn = createCallbackFcn(app, @SeevelocitiesButtonPushed, true);
            app.SeevelocitiesButton.Text = 'See velocities';
            app.AccelerationsGraphsTab = uitab(app.TabGroup);
            app.AccelerationsGraphsTab.Title = 'Accelerations Graphs';
            app.UIAxes6 = uiaxes(app.AccelerationsGraphsTab);
            title(app.UIAxes6, 'Acceleration analysis of mechanism')
            xlabel(app.UIAxes6, 'theta 1 (rad)')
            ylabel(app.UIAxes6, 'Alpha 2 (rad/s^2)')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.AccelerationsGraphsTab);
            title(app.UIAxes7, 'Acceleration analysis of mechanism')
            xlabel(app.UIAxes7, 'theta 1 (rad)')
            ylabel(app.UIAxes7, 'adx(m/s^2)')
            zlabel(app.UIAxes7, 'Z')
            app.ShowaccelerationsButton = uibutton(app.AccelerationsGraphsTab, 'push');
            app.ShowaccelerationsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowaccelerationsButtonPushed, true);
            app.ShowaccelerationsButton.Text = 'Show accelerations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appproyect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end