classdef wS4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RepresentativeforceofaGaussianlikedistributionPanel  matlab.ui.container.Panel
        Sampledensitieskgm3Panel        matlab.ui.container.Panel
        Gold19300Label                  matlab.ui.control.Label
        Water1000Label                  matlab.ui.control.Label
        WoodAverage1500Label            matlab.ui.control.Label
        SteelAverage7950Label           matlab.ui.control.Label
        Paper800Label                   matlab.ui.control.Label
        Iron7800Label                   matlab.ui.control.Label
        Aream2EditField                 matlab.ui.control.NumericEditField
        Aream2EditFieldLabel            matlab.ui.control.Label
        CharacteristicsofrepresentativeforceDonoteditPanel  matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        WNEditField                     matlab.ui.control.NumericEditField
        WNEditFieldLabel                matlab.ui.control.Label
        ActingpointcentroidonxmEditField  matlab.ui.control.NumericEditField
        ActingpointcentroidonxmEditFieldLabel  matlab.ui.control.Label
        WeightpropertiesgravityanddensityPanel  matlab.ui.container.Panel
        kgm3EditField                   matlab.ui.control.NumericEditField
        kgm3EditFieldLabel              matlab.ui.control.Label
        gms2EditField                   matlab.ui.control.NumericEditField
        gms2EditFieldLabel              matlab.ui.control.Label
        IndicationsPanel                matlab.ui.container.Panel
        InteractwiththesevaluesgravityanddensityforWLabel  matlab.ui.control.Label
        thecentroidoftheareaonthexasisinthiscaseLabel  matlab.ui.control.Label
        totheareawhichrepresentsthebookscenteredinLabel  matlab.ui.control.Label
        understandingtherepresentativeloadisequivalentLabel  matlab.ui.control.Label
        systemwhichisonX0andY0axesforaneasyLabel  matlab.ui.control.Label
        thecombinationofareaaandwiththecoordinatedLabel  matlab.ui.control.Label
        andtheirrangeTherepresentativeloadisgivenbyLabel  matlab.ui.control.Label
        thebooksandtheirlocationtherepresentativeareaLabel  matlab.ui.control.Label
        withtheintegrationyoucanchocetheaproximationofLabel  matlab.ui.control.Label
        ThevaluedisplacesthebooksmainaproximationLabel  matlab.ui.control.Label
        GaussianfuntionpropertiesPanel  matlab.ui.container.Panel
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        IntegrationArealimitsbookslengthinmPanel  matlab.ui.container.Panel
        limitbEditField                 matlab.ui.control.NumericEditField
        limitbEditFieldLabel            matlab.ui.control.Label
        limitaEditField                 matlab.ui.control.NumericEditField
        limitaEditFieldLabel            matlab.ui.control.Label
        PlotButton                      matlab.ui.control.StateButton
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function plotting(app)
            value = app.PlotButton.Value;

        x= linspace(-100,100,1000000);
        
        limitA= app.limitaEditField.Value;
        limitB= app.limitbEditField.Value;

        integration = linspace(limitA,limitB,1000);


        a = app.aEditField.Value;
        sigma = app.EditField.Value;
        miu = app.EditField_2.Value;
        xmin1 = -5;
        acceleration = app.gms2EditField.Value;
        density = app.kgm3EditField.Value;
        
        maxgaussian =((a/(sigma*sqrt(2.*pi))).*exp(-(1/2)*((miu)./(sigma)).^2));


        y=((a/(sigma*sqrt(2.*pi))).*exp(-(1/2)*((x-miu)./(sigma)).^2));
        y2=((a/(sigma*sqrt(2.*pi))).*exp(-(1/2)*((integration-miu)./(sigma)).^2));
        
        %important
        app.Aream2EditField.Value = ((a/2)*erf((limitB-miu)/(sqrt(2)*sigma)))-((a/2)*erf((limitA-miu)/(sqrt(2)*sigma)));

        integralpart1 = (-(a.*sigma.*exp(-(miu-limitB).^2/(2.*(sigma)^2)))/sqrt(2.*pi)-(1./2).*a.*miu.*erf((miu-limitB)/(sqrt(2).*sigma)));
        integralpart2 = (-(a.*sigma.*exp(-(miu-limitA).^2/(2.*(sigma)^2)))/sqrt(2.*pi)-(1./2).*a.*miu.*erf((miu-limitA)/(sqrt(2).*sigma)));
        
        %important
        app.ActingpointcentroidonxmEditField.Value= (integralpart1-integralpart2)./(app.Aream2EditField.Value);

        
        %important
        app.WNEditField.Value= (app.Aream2EditField.Value.*acceleration.*density);


        plot(app.UIAxes,x,y);

        hold(app.UIAxes,"on")

        
        area(app.UIAxes,integration,y2,'FaceColor', 'blue', 'FaceAlpha', 0.3);
        plot(app.UIAxes,[xmin1, limitB+5], [0, 0], '-k', 'LineWidth', 0.25); % horizontal line
        plot(app.UIAxes,[0, 0], [-0.125, maxgaussian+0.125], '-k', 'LineWidth', 0.25); % vertical line
        plot(app.UIAxes,[app.ActingpointcentroidonxmEditField.Value , app.ActingpointcentroidonxmEditField.Value ], [0,maxgaussian+0.25], '-k', 'LineWidth', 0.25,'Color','green');
        
        hold(app.UIAxes,"off")
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plotting(app);
        end

        % Value changed function: PlotButton
        function PlotButtonValueChanged(app, event)
        
         plotting(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RepresentativeforceofaGaussianlikedistributionPanel = uipanel(app.UIFigure);
            app.RepresentativeforceofaGaussianlikedistributionPanel.Title = 'Representative force of a Gaussian-like distribution';
            app.UIAxes = uiaxes(app.RepresentativeforceofaGaussianlikedistributionPanel);
            title(app.UIAxes, 'System of Reference')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.RepresentativeforceofaGaussianlikedistributionPanel, 'state');
            app.PlotButton.ValueChangedFcn = createCallbackFcn(app, @PlotButtonValueChanged, true);
            app.PlotButton.Text = 'Plot';
            app.IntegrationArealimitsbookslengthinmPanel = uipanel(app.RepresentativeforceofaGaussianlikedistributionPanel);
            app.IntegrationArealimitsbookslengthinmPanel.Title = 'Integration/Area limits (books length) in m';
            app.limitaEditFieldLabel = uilabel(app.IntegrationArealimitsbookslengthinmPanel);
            app.limitaEditFieldLabel.Text = 'limit a';
            app.limitaEditField = uieditfield(app.IntegrationArealimitsbookslengthinmPanel, 'numeric');
            app.limitbEditFieldLabel = uilabel(app.IntegrationArealimitsbookslengthinmPanel);
            app.limitbEditFieldLabel.Text = 'limit b';
            app.limitbEditField = uieditfield(app.IntegrationArealimitsbookslengthinmPanel, 'numeric');
            app.limitbEditField.Value = 3;
            app.GaussianfuntionpropertiesPanel = uipanel(app.RepresentativeforceofaGaussianlikedistributionPanel);
            app.GaussianfuntionpropertiesPanel.Title = 'Gaussian funtion properties:';
            app.Label = uilabel(app.GaussianfuntionpropertiesPanel);
            app.Label.Text = 'σ';
            app.EditField = uieditfield(app.GaussianfuntionpropertiesPanel, 'numeric');
            app.EditField.Value = 1;
            app.EditField_2Label = uilabel(app.GaussianfuntionpropertiesPanel);
            app.EditField_2Label.Text = 'μ';
            app.EditField_2 = uieditfield(app.GaussianfuntionpropertiesPanel, 'numeric');
            app.EditField_2.Value = 1;
            app.aEditFieldLabel = uilabel(app.GaussianfuntionpropertiesPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.GaussianfuntionpropertiesPanel, 'numeric');
            app.aEditField.Value = 1;
            app.IndicationsPanel = uipanel(app.RepresentativeforceofaGaussianlikedistributionPanel);
            app.IndicationsPanel.Title = 'Indications:';
            app.ThevaluedisplacesthebooksmainaproximationLabel = uilabel(app.IndicationsPanel);
            app.ThevaluedisplacesthebooksmainaproximationLabel.Text = 'The μ value displaces the "book''s" main aproximation,';
            app.withtheintegrationyoucanchocetheaproximationofLabel = uilabel(app.IndicationsPanel);
            app.withtheintegrationyoucanchocetheaproximationofLabel.Text = 'with the integration you can choce the aproximation of';
            app.thebooksandtheirlocationtherepresentativeareaLabel = uilabel(app.IndicationsPanel);
            app.thebooksandtheirlocationtherepresentativeareaLabel.Text = 'the books and their location (the representative area ';
            app.andtheirrangeTherepresentativeloadisgivenbyLabel = uilabel(app.IndicationsPanel);
            app.andtheirrangeTherepresentativeloadisgivenbyLabel.Text = 'and their range). The representative load is given by ';
            app.thecombinationofareaaandwiththecoordinatedLabel = uilabel(app.IndicationsPanel);
            app.thecombinationofareaaandwiththecoordinatedLabel.Text = 'the combination of area, a, σ and μ with the coordinated   ';
            app.systemwhichisonX0andY0axesforaneasyLabel = uilabel(app.IndicationsPanel);
            app.systemwhichisonX0andY0axesforaneasyLabel.Text = 'system, which is on X=0 and Y=0 axes for an easy ';
            app.understandingtherepresentativeloadisequivalentLabel = uilabel(app.IndicationsPanel);
            app.understandingtherepresentativeloadisequivalentLabel.Text = 'understanding, the representative load is equivalent ';
            app.totheareawhichrepresentsthebookscenteredinLabel = uilabel(app.IndicationsPanel);
            app.totheareawhichrepresentsthebookscenteredinLabel.Text = 'to the area, which represents the books, centered in';
            app.thecentroidoftheareaonthexasisinthiscaseLabel = uilabel(app.IndicationsPanel);
            app.thecentroidoftheareaonthexasisinthiscaseLabel.Text = 'the centroid of the area on the x asis in this case. ';
            app.InteractwiththesevaluesgravityanddensityforWLabel = uilabel(app.IndicationsPanel);
            app.InteractwiththesevaluesgravityanddensityforWLabel.Text = 'Interact with these values, gravity and density for W.';
            app.WeightpropertiesgravityanddensityPanel = uipanel(app.RepresentativeforceofaGaussianlikedistributionPanel);
            app.WeightpropertiesgravityanddensityPanel.Title = 'Weight properties (gravity and density)';
            app.gms2EditFieldLabel = uilabel(app.WeightpropertiesgravityanddensityPanel);
            app.gms2EditFieldLabel.Text = 'g (m/s^2)';
            app.gms2EditField = uieditfield(app.WeightpropertiesgravityanddensityPanel, 'numeric');
            app.gms2EditField.Value = 9.813;
            app.kgm3EditFieldLabel = uilabel(app.WeightpropertiesgravityanddensityPanel);
            app.kgm3EditFieldLabel.Text = 'ρ (kg/m^3)';
            app.kgm3EditField = uieditfield(app.WeightpropertiesgravityanddensityPanel, 'numeric');
            app.kgm3EditField.Value = 800;
            app.CharacteristicsofrepresentativeforceDonoteditPanel = uipanel(app.RepresentativeforceofaGaussianlikedistributionPanel);
            app.CharacteristicsofrepresentativeforceDonoteditPanel.Title = 'Characteristics of representative force (Do not edit):';
            app.ActingpointcentroidonxmEditFieldLabel = uilabel(app.CharacteristicsofrepresentativeforceDonoteditPanel);
            app.ActingpointcentroidonxmEditFieldLabel.Text = 'Acting point/centroid on x (m)';
            app.ActingpointcentroidonxmEditField = uieditfield(app.CharacteristicsofrepresentativeforceDonoteditPanel, 'numeric');
            app.WNEditFieldLabel = uilabel(app.CharacteristicsofrepresentativeforceDonoteditPanel);
            app.WNEditFieldLabel.Text = 'W (N)';
            app.WNEditField = uieditfield(app.CharacteristicsofrepresentativeforceDonoteditPanel, 'numeric');
            app.Lamp = uilamp(app.CharacteristicsofrepresentativeforceDonoteditPanel);
            app.Aream2EditFieldLabel = uilabel(app.RepresentativeforceofaGaussianlikedistributionPanel);
            app.Aream2EditFieldLabel.Text = 'Area (m^2)';
            app.Aream2EditField = uieditfield(app.RepresentativeforceofaGaussianlikedistributionPanel, 'numeric');
            app.Sampledensitieskgm3Panel = uipanel(app.RepresentativeforceofaGaussianlikedistributionPanel);
            app.Sampledensitieskgm3Panel.Title = 'Sample densities (kg/m^3):';
            app.Iron7800Label = uilabel(app.Sampledensitieskgm3Panel);
            app.Iron7800Label.Text = 'Iron: 7800';
            app.Paper800Label = uilabel(app.Sampledensitieskgm3Panel);
            app.Paper800Label.Text = 'Paper: 800';
            app.SteelAverage7950Label = uilabel(app.Sampledensitieskgm3Panel);
            app.SteelAverage7950Label.Text = 'Steel (Average): 7950';
            app.WoodAverage1500Label = uilabel(app.Sampledensitieskgm3Panel);
            app.WoodAverage1500Label.Text = 'Wood (Average): 1500';
            app.Water1000Label = uilabel(app.Sampledensitieskgm3Panel);
            app.Water1000Label.Text = 'Water: 1000';
            app.Gold19300Label = uilabel(app.Sampledensitieskgm3Panel);
            app.Gold19300Label.Text = 'Gold: 19300';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wS4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end